classdef app_UAV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UAVsyPaquetesPanel           matlab.ui.container.Panel
        UAVsSpinnerLabel             matlab.ui.control.Label
        UAVsSpinner                  matlab.ui.control.Spinner
        PaquetesUAVSpinnerLabel      matlab.ui.control.Label
        PaquetesUAVSpinner           matlab.ui.control.Spinner
        AnadirButton                 matlab.ui.control.Button
        CantidaddeUAVsLabel          matlab.ui.control.Label
        NumeromediodepaquetesporUAVLabel  matlab.ui.control.Label
        SimularButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        DimensionesdeescenarioPanel  matlab.ui.container.Panel
        AnchoxAltoSliderLabel        matlab.ui.control.Label
        AnchoxAltoSlider             matlab.ui.control.Slider
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Gráficas para feedback de UAV y paquetes añadidos
            plot3(app.UIAxes,0,0,0);
            title(app.UIAxes,'ESCENARIO');
                        
            bar(app.UIAxes2,0);
            title(app.UIAxes2,'PAQUETES');
            ylabel(app.UIAxes2,'paquetes totales');  % etiqueta para el nombre del eje y 
        end

        % Button pushed function: AnadirButton
        function AnadirButtonPushed(app, event)
            % Leo valor, numero UAVs introducidos y tamaño de escenario
            nUAV = app.UAVsSpinner.Value;
            paquetes = app.PaquetesUAVSpinner.Value;
            escenario = app.AnchoxAltoSlider.Value;
            
            % Creo fichero con destinos random
            destinationsRandom = fopen('destinationUAVrandom.csv','w');
            
            % Genero matriz de nxn numeros aleatorios
            r = -escenario +(escenario+escenario)*rand(nUAV*paquetes,2);
            r2 = -nUAV +(nUAV+nUAV)*rand(nUAV,2);
            assignin("base","R",r);
            assignin("base","R2",r2);
            assignin("base","R3",paquetes);
            len = size(r);
            for UAVid=1:len(1)
                fprintf(destinationsRandom,"%f,%f\n",r(UAVid,1),r(UAVid,2));
            end
            
            fclose(destinationsRandom);
            msgbox('UAVs añadidos.','Success');
            
            %Gráficas para feedback de UAV y paquetes añadidos
            plot3(app.UIAxes,r2(:,1),r2(:,2),zeros(size(r2,1)),'r:o');
            legend(app.UIAxes,'UAVs posicion inicial');
            title(app.UIAxes,'ESCENARIO');
                        
            bar(app.UIAxes2,nUAV*paquetes);
            title(app.UIAxes2,'PAQUETES');
            ylabel(app.UIAxes2,'paquetes totales');  % etiqueta para el nombre del eje y        
          
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            open_system("simulator.slx");
            sim("simulator.slx");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UAVsyPaquetesPanel = uipanel(app.UIFigure);
            app.UAVsyPaquetesPanel.Title = 'UAVs y Paquetes';
            app.UAVsSpinnerLabel = uilabel(app.UAVsyPaquetesPanel);
            app.UAVsSpinnerLabel.Text = 'UAVs';
            app.UAVsSpinner = uispinner(app.UAVsyPaquetesPanel);
            app.UAVsSpinner.Limits = [1 50];
            app.UAVsSpinner.Value = 1;
            app.PaquetesUAVSpinnerLabel = uilabel(app.UAVsyPaquetesPanel);
            app.PaquetesUAVSpinnerLabel.Text = 'Paquetes/UAV';
            app.PaquetesUAVSpinner = uispinner(app.UAVsyPaquetesPanel);
            app.PaquetesUAVSpinner.Limits = [1 Inf];
            app.PaquetesUAVSpinner.Value = 1;
            app.AnadirButton = uibutton(app.UAVsyPaquetesPanel, 'push');
            app.AnadirButton.ButtonPushedFcn = createCallbackFcn(app, @AnadirButtonPushed, true);
            app.AnadirButton.Text = 'Anadir';
            app.CantidaddeUAVsLabel = uilabel(app.UAVsyPaquetesPanel);
            app.CantidaddeUAVsLabel.Text = 'Cantidad de UAVs';
            app.NumeromediodepaquetesporUAVLabel = uilabel(app.UAVsyPaquetesPanel);
            app.NumeromediodepaquetesporUAVLabel.Text = 'Numero medio de paquetes por UAV';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DimensionesdeescenarioPanel = uipanel(app.UIFigure);
            app.DimensionesdeescenarioPanel.Title = 'Dimensiones de escenario';
            app.AnchoxAltoSliderLabel = uilabel(app.DimensionesdeescenarioPanel);
            app.AnchoxAltoSliderLabel.Text = 'Ancho x Alto';
            app.AnchoxAltoSlider = uislider(app.DimensionesdeescenarioPanel);
            app.AnchoxAltoSlider.Limits = [0 1000];
            app.AnchoxAltoSlider.Value = 500;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_UAV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end